classdef LightSheet_Simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SaveworkspaceEditFieldLabel     matlab.ui.control.Label
        SaveworkspaceEditField          matlab.ui.control.EditField
        SaveButton                      matlab.ui.control.Button
        AnalysisPanel                   matlab.ui.container.Panel
        AnalyzeButton                   matlab.ui.control.Button
        MainLobeWidthTextAreaLabel      matlab.ui.control.Label
        MainLobeWidthTextArea           matlab.ui.control.TextArea
        LengthTextAreaLabel             matlab.ui.control.Label
        LengthTextArea                  matlab.ui.control.TextArea
        OpticalSectioningTextAreaLabel  matlab.ui.control.Label
        OpticalSectioningTextArea       matlab.ui.control.TextArea
        refractiveindexEditFieldLabel   matlab.ui.control.Label
        refractiveindexEditField        matlab.ui.control.NumericEditField
        SimulateButton                  matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        GaussianbeamTab                 matlab.ui.container.Tab
        NAEditFieldLabel                matlab.ui.control.Label
        NAGaussian                      matlab.ui.control.NumericEditField
        FocusedFlatTopbeamTab           matlab.ui.container.Tab
        NAEditField_2Label              matlab.ui.control.Label
        NAFlatTop                       matlab.ui.control.NumericEditField
        AirybeamTab                     matlab.ui.container.Tab
        NAEditField_3Label              matlab.ui.control.Label
        NAAiry                          matlab.ui.control.NumericEditField
        amplitudeEditFieldLabel         matlab.ui.control.Label
        amplitudeAiry                   matlab.ui.control.NumericEditField
        amplitude0Label                 matlab.ui.control.Label
        DitheredBesselbeamLatticeTab    matlab.ui.container.Tab
        NAEditField_4Label              matlab.ui.control.Label
        NALattice                       matlab.ui.control.NumericEditField
        epsilonEditFieldLabel           matlab.ui.control.Label
        epsilonLattice                  matlab.ui.control.NumericEditField
        epsilon1Label                   matlab.ui.control.Label
        SphericallyAberratedbeamTab     matlab.ui.container.Tab
        NAEditField_6Label              matlab.ui.control.Label
        NASpherical                     matlab.ui.control.NumericEditField
        amplitudeEditField_2Label       matlab.ui.control.Label
        amplitudeSpherical              matlab.ui.control.NumericEditField
        amplitude0Label_2               matlab.ui.control.Label
        DoublebeamTab                   matlab.ui.container.Tab
        NAEditField_5Label              matlab.ui.control.Label
        NADouble                        matlab.ui.control.NumericEditField
        separationEditFieldLabel        matlab.ui.control.Label
        offsetDouble                    matlab.ui.control.NumericEditField
        separation0Label                matlab.ui.control.Label
        BesselbeamTab                   matlab.ui.container.Tab
        NAEditField_7Label              matlab.ui.control.Label
        NABessel                        matlab.ui.control.NumericEditField
        epsilonEditField_2Label         matlab.ui.control.Label
        epsilonBessel                   matlab.ui.control.NumericEditField
        epsilon1Label_2                 matlab.ui.control.Label
        UploadTab                       matlab.ui.container.Tab
        AmplitudeEditFieldLabel         matlab.ui.control.Label
        IntensityUpload                 matlab.ui.control.EditField
        PhaseEditFieldLabel             matlab.ui.control.Label
        PhaseUpload                     matlab.ui.control.EditField
        wavelengthnmLabel               matlab.ui.control.Label
        wavelengthnmEditField           matlab.ui.control.NumericEditField
        ExcitationDropDownLabel         matlab.ui.control.Label
        ExcitationDropDown              matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
    end


    properties (Access = private)
        NA % Description
        aptype % Description
        phasetype % Description
        illPSF_mat % Description
        cy % Description
        cy_pad
        num_par % Description
        illPSF % Description
        PSFSumY % Description
        ml % Description
        os % Description
        length % Description
        sep_double % Description
        options % Description
        saveto % Description
        amptiff % Description
        phasetiff % Description
        amp_in_BFP
        phase_in_BFP
        ref_index = 1.33;
        lambda = 488;
        excitation = 'Linear';
    end


    methods (Access = private)

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            [app.os, app.ml, app.length]=analysisbeam_gui(app.PSFSumY,app.cy,app.num_par);
            if isnan(app.ml)
                app.MainLobeWidthTextArea.Value = 'Error';
                app.OpticalSectioningTextArea.Value = 'Error';
                app.LengthTextArea.Value = 'Error';
                error('Beam too large')
            else
                app.MainLobeWidthTextArea.Value = string(app.ml);
                app.OpticalSectioningTextArea.Value = string(app.os);
                app.LengthTextArea.Value = string(app.length);
            end
        end

        % Value changed function: NAGaussian
        function NAGaussianValueChanged(app, event)
            app.NA = app.NAGaussian.Value;
            app.aptype='gaussian';
            app.phasetype=' ';
            clear app.options
            
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            close all
            app.MainLobeWidthTextArea.Value = '';
            app.OpticalSectioningTextArea.Value = '';
            app.LengthTextArea.Value = '';
            
            [app.illPSF_mat,illPSF_Ix0,illPSF_EinBFP,app.amp_in_BFP,app.phase_in_BFP,app.cy,app.cy_pad,cz,app.num_par,phys_par,app.illPSF]=simulatebeam_gui(app.aptype,app.phasetype,app.NA,0,app.lambda,app.ref_index,app.excitation,app.options);
            
            [~,app.PSFSumY]=integrate_and_slice_gui(app.illPSF_mat,illPSF_Ix0,illPSF_EinBFP,cz,app.cy,app.cy_pad,app.num_par,phys_par,app.illPSF,app.phase_in_BFP);
        end

        % Value changed function: NADouble
        function NADoubleValueChanged(app, event)
            app.NA = app.NADouble.Value;
            app.aptype='double-gaussian';
            app.phasetype='';
        end

        % Value changed function: offsetDouble
        function offsetDoubleValueChanged(app, event)
            app.options = app.offsetDouble.Value;
            
        end

        % Value changed function: NAFlatTop
        function NAFlatTopValueChanged(app, event)
            app.NA = app.NAFlatTop.Value;
            app.aptype='circ';
            app.phasetype='';
            clear app.options
        end

        % Value changed function: NAAiry
        function NAAiryValueChanged(app, event)
            app.NA = app.NAAiry.Value;
            app.aptype='circ';
            app.phasetype='airy';
        end

        % Value changed function: NALattice
        function NAlattice_ValueChanged(app, event)
            app.NA = app.NALattice.Value;
            app.aptype='lattice';
            app.phasetype='';
        end

        % Value changed function: amplitudeAiry
        function amplitudeAiryValueChanged(app, event)
            app.options = app.amplitudeAiry.Value;
            
        end

        % Value changed function: epsilonLattice
        function epsilonLatticeValueChanged(app, event)
            app.options = app.epsilonLattice.Value;
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            amplitude = app.amp_in_BFP(app.cy_pad-app.cy+1:app.cy_pad+app.cy,app.cy_pad-app.cy+1:app.cy_pad+app.cy);
            phase = app.phase_in_BFP(app.cy_pad-app.cy+1:app.cy_pad+app.cy,app.cy_pad-app.cy+1:app.cy_pad+app.cy);
            imwrite(double(amplitude),[app.saveto 'amplitude.tiff'])
            imwrite(double(phase),[app.saveto 'phase.tiff'])
       
        end

        % Value changed function: SaveworkspaceEditField
        function SaveworkspaceEditFieldValueChanged(app, event)
            app.saveto = app.SaveworkspaceEditField.Value;
            
        end

        % Value changed function: IntensityUpload
        function IntensityUploadValueChanged(app, event)
            app.options{1} = imread(app.IntensityUpload.Value);
            app.aptype = 'uploadtiff';
            
        end

        % Value changed function: PhaseUpload
        function PhaseUploadValueChanged(app, event)
             app.options{2} = imread(app.PhaseUpload.Value);
            
        end

        % Value changed function: NASpherical
        function NASphericalValueChanged(app, event)
            app.NA = app.NASpherical.Value;
            app.aptype='circ';
            app.phasetype='SA';            
        end

        % Value changed function: amplitudeSpherical
        function amplitudeSphericalValueChanged(app, event)
            app.options = app.amplitudeSpherical.Value;
            
        end

        % Value changed function: wavelengthnmEditField
        function wavelengthnmEditFieldValueChanged(app, event)
            app.lambda = app.wavelengthnmEditField.Value;
            
        end

        % Value changed function: refractiveindexEditField
        function refractiveindexEditFieldValueChanged(app, event)
            app.ref_index = app.refractiveindexEditField.Value;
            
        end

        % Callback function
        function ExcitationButtonGroupSelectionChanged(app, event)
            app.excitation = app.ExcitationButtonGroup.SelectedObject;
            
        end

        % Value changed function: ExcitationDropDown
        function ExcitationDropDownValueChanged(app, event)
            app.excitation = app.ExcitationDropDown.Value;
            
        end

        % Value changed function: NABessel
        function NABesselValueChanged(app, event)
            app.NA = app.NABessel.Value;
            app.aptype = 'bessel';
            app.phasetype='';
        end

        % Value changed function: epsilonBessel
        function epsilonBesselValueChanged(app, event)
            app.options = app.epsilonBessel.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SaveworkspaceEditFieldLabel = uilabel(app.UIFigure);
            app.SaveworkspaceEditFieldLabel.Text = 'Save workspace:';
            app.SaveworkspaceEditField = uieditfield(app.UIFigure, 'text');
            app.SaveworkspaceEditField.ValueChangedFcn = createCallbackFcn(app, @SaveworkspaceEditFieldValueChanged, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.AnalysisPanel = uipanel(app.UIFigure);
            app.AnalysisPanel.Title = 'Analysis';
            app.AnalyzeButton = uibutton(app.AnalysisPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.MainLobeWidthTextAreaLabel = uilabel(app.AnalysisPanel);
            app.MainLobeWidthTextAreaLabel.Text = 'Main Lobe Width';
            app.MainLobeWidthTextArea = uitextarea(app.AnalysisPanel);
            app.MainLobeWidthTextArea.Editable = 'off';
            app.LengthTextAreaLabel = uilabel(app.AnalysisPanel);
            app.LengthTextAreaLabel.Text = 'Length';
            app.LengthTextArea = uitextarea(app.AnalysisPanel);
            app.LengthTextArea.Editable = 'off';
            app.OpticalSectioningTextAreaLabel = uilabel(app.AnalysisPanel);
            app.OpticalSectioningTextAreaLabel.Text = 'Optical Sectioning';
            app.OpticalSectioningTextArea = uitextarea(app.AnalysisPanel);
            app.OpticalSectioningTextArea.Editable = 'off';
            app.refractiveindexEditFieldLabel = uilabel(app.UIFigure);
            app.refractiveindexEditFieldLabel.Text = 'refractive index';
            app.refractiveindexEditField = uieditfield(app.UIFigure, 'numeric');
            app.refractiveindexEditField.ValueChangedFcn = createCallbackFcn(app, @refractiveindexEditFieldValueChanged, true);
            app.refractiveindexEditField.Value = 1.33;
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GaussianbeamTab = uitab(app.TabGroup);
            app.GaussianbeamTab.Title = 'Gaussian beam';
            app.NAEditFieldLabel = uilabel(app.GaussianbeamTab);
            app.NAEditFieldLabel.Text = 'NA';
            app.NAGaussian = uieditfield(app.GaussianbeamTab, 'numeric');
            app.NAGaussian.ValueChangedFcn = createCallbackFcn(app, @NAGaussianValueChanged, true);
            app.FocusedFlatTopbeamTab = uitab(app.TabGroup);
            app.FocusedFlatTopbeamTab.Title = 'Focused Flat-Top beam';
            app.NAEditField_2Label = uilabel(app.FocusedFlatTopbeamTab);
            app.NAEditField_2Label.Text = 'NA';
            app.NAFlatTop = uieditfield(app.FocusedFlatTopbeamTab, 'numeric');
            app.NAFlatTop.ValueChangedFcn = createCallbackFcn(app, @NAFlatTopValueChanged, true);
            app.AirybeamTab = uitab(app.TabGroup);
            app.AirybeamTab.Title = 'Airy beam';
            app.NAEditField_3Label = uilabel(app.AirybeamTab);
            app.NAEditField_3Label.Text = 'NA';
            app.NAAiry = uieditfield(app.AirybeamTab, 'numeric');
            app.NAAiry.ValueChangedFcn = createCallbackFcn(app, @NAAiryValueChanged, true);
            app.amplitudeEditFieldLabel = uilabel(app.AirybeamTab);
            app.amplitudeEditFieldLabel.Text = 'amplitude';
            app.amplitudeAiry = uieditfield(app.AirybeamTab, 'numeric');
            app.amplitudeAiry.ValueChangedFcn = createCallbackFcn(app, @amplitudeAiryValueChanged, true);
            app.amplitude0Label = uilabel(app.AirybeamTab);
            app.amplitude0Label.Text = 'amplitude > 0';
            app.DitheredBesselbeamLatticeTab = uitab(app.TabGroup);
            app.DitheredBesselbeamLatticeTab.Title = 'Dithered Bessel beam Lattice';
            app.NAEditField_4Label = uilabel(app.DitheredBesselbeamLatticeTab);
            app.NAEditField_4Label.Text = 'NA';
            app.NALattice = uieditfield(app.DitheredBesselbeamLatticeTab, 'numeric');
            app.NALattice.ValueChangedFcn = createCallbackFcn(app, @NAlattice_ValueChanged, true);
            app.epsilonEditFieldLabel = uilabel(app.DitheredBesselbeamLatticeTab);
            app.epsilonEditFieldLabel.Text = 'epsilon';
            app.epsilonLattice = uieditfield(app.DitheredBesselbeamLatticeTab, 'numeric');
            app.epsilonLattice.ValueChangedFcn = createCallbackFcn(app, @epsilonLatticeValueChanged, true);
            app.epsilon1Label = uilabel(app.DitheredBesselbeamLatticeTab);
            app.epsilon1Label.Text = '0 < epsilon < 1';
            app.SphericallyAberratedbeamTab = uitab(app.TabGroup);
            app.SphericallyAberratedbeamTab.Title = 'Spherically Aberrated beam';
            app.NAEditField_6Label = uilabel(app.SphericallyAberratedbeamTab);
            app.NAEditField_6Label.Text = 'NA';
            app.NASpherical = uieditfield(app.SphericallyAberratedbeamTab, 'numeric');
            app.NASpherical.ValueChangedFcn = createCallbackFcn(app, @NASphericalValueChanged, true);
            app.amplitudeEditField_2Label = uilabel(app.SphericallyAberratedbeamTab);
            app.amplitudeEditField_2Label.Text = 'amplitude';
            app.amplitudeSpherical = uieditfield(app.SphericallyAberratedbeamTab, 'numeric');
            app.amplitudeSpherical.ValueChangedFcn = createCallbackFcn(app, @amplitudeSphericalValueChanged, true);
            app.amplitude0Label_2 = uilabel(app.SphericallyAberratedbeamTab);
            app.amplitude0Label_2.Text = 'amplitude > 0';
            app.DoublebeamTab = uitab(app.TabGroup);
            app.DoublebeamTab.Title = 'Double beam';
            app.NAEditField_5Label = uilabel(app.DoublebeamTab);
            app.NAEditField_5Label.Text = 'NA';
            app.NADouble = uieditfield(app.DoublebeamTab, 'numeric');
            app.NADouble.ValueChangedFcn = createCallbackFcn(app, @NADoubleValueChanged, true);
            app.separationEditFieldLabel = uilabel(app.DoublebeamTab);
            app.separationEditFieldLabel.Text = 'separation';
            app.offsetDouble = uieditfield(app.DoublebeamTab, 'numeric');
            app.offsetDouble.ValueChangedFcn = createCallbackFcn(app, @offsetDoubleValueChanged, true);
            app.separation0Label = uilabel(app.DoublebeamTab);
            app.separation0Label.Text = 'separation > 0';
            app.BesselbeamTab = uitab(app.TabGroup);
            app.BesselbeamTab.Title = 'Bessel beam';
            app.NAEditField_7Label = uilabel(app.BesselbeamTab);
            app.NAEditField_7Label.Text = 'NA';
            app.NABessel = uieditfield(app.BesselbeamTab, 'numeric');
            app.NABessel.ValueChangedFcn = createCallbackFcn(app, @NABesselValueChanged, true);
            app.epsilonEditField_2Label = uilabel(app.BesselbeamTab);
            app.epsilonEditField_2Label.Text = 'epsilon';
            app.epsilonBessel = uieditfield(app.BesselbeamTab, 'numeric');
            app.epsilonBessel.ValueChangedFcn = createCallbackFcn(app, @epsilonBesselValueChanged, true);
            app.epsilon1Label_2 = uilabel(app.BesselbeamTab);
            app.epsilon1Label_2.Text = '0 < epsilon < 1';
            app.UploadTab = uitab(app.TabGroup);
            app.UploadTab.Title = 'Upload ';
            app.AmplitudeEditFieldLabel = uilabel(app.UploadTab);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.IntensityUpload = uieditfield(app.UploadTab, 'text');
            app.IntensityUpload.ValueChangedFcn = createCallbackFcn(app, @IntensityUploadValueChanged, true);
            app.PhaseEditFieldLabel = uilabel(app.UploadTab);
            app.PhaseEditFieldLabel.Text = 'Phase';
            app.PhaseUpload = uieditfield(app.UploadTab, 'text');
            app.PhaseUpload.ValueChangedFcn = createCallbackFcn(app, @PhaseUploadValueChanged, true);
            app.wavelengthnmLabel = uilabel(app.UIFigure);
            app.wavelengthnmLabel.Text = 'wavelength  (nm)';
            app.wavelengthnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.wavelengthnmEditField.ValueChangedFcn = createCallbackFcn(app, @wavelengthnmEditFieldValueChanged, true);
            app.wavelengthnmEditField.Value = 488;
            app.ExcitationDropDownLabel = uilabel(app.UIFigure);
            app.ExcitationDropDownLabel.Text = 'Excitation';
            app.ExcitationDropDown = uidropdown(app.UIFigure);
            app.ExcitationDropDown.Items = {'Linear', 'Two photon'};
            app.ExcitationDropDown.ValueChangedFcn = createCallbackFcn(app, @ExcitationDropDownValueChanged, true);
            app.ExcitationDropDown.Value = 'Linear';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'See Appendix B for a more detailed explanation on the simulation parameters';
        end
    end

    methods (Access = public)

        % Construct app
        function app = LightSheet_Simulator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end