classdef beamsimulator_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SaveworkspaceEditFieldLabel     matlab.ui.control.Label
        SaveworkspaceEditField          matlab.ui.control.EditField
        SaveButton                      matlab.ui.control.Button
        AnalysisPanel                   matlab.ui.container.Panel
        AnalyzeButton                   matlab.ui.control.Button
        MainLobeWidthTextAreaLabel      matlab.ui.control.Label
        MainLobeWidthTextArea           matlab.ui.control.TextArea
        LengthTextAreaLabel             matlab.ui.control.Label
        LengthTextArea                  matlab.ui.control.TextArea
        OpticalSectioningTextAreaLabel  matlab.ui.control.Label
        OpticalSectioningTextArea       matlab.ui.control.TextArea
        refractiveindexEditFieldLabel   matlab.ui.control.Label
        refractiveindexEditField        matlab.ui.control.NumericEditField
        SimulateButton                  matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        GaussianBeamTab                 matlab.ui.container.Tab
        NAEditFieldLabel                matlab.ui.control.Label
        NAEditField                     matlab.ui.control.NumericEditField
        FocusedFlatTopBeamTab           matlab.ui.container.Tab
        NAEditField_2Label              matlab.ui.control.Label
        NAEditField_2                   matlab.ui.control.NumericEditField
        AiryBeamTab                     matlab.ui.container.Tab
        NAEditField_3Label              matlab.ui.control.Label
        NAEditField_3                   matlab.ui.control.NumericEditField
        amplitudeEditFieldLabel         matlab.ui.control.Label
        amplitudeEditField              matlab.ui.control.NumericEditField
        DitheredLatticeTab              matlab.ui.container.Tab
        NAEditField_4Label              matlab.ui.control.Label
        NAEditField_4                   matlab.ui.control.NumericEditField
        epsilonEditFieldLabel           matlab.ui.control.Label
        epsilonEditField                matlab.ui.control.NumericEditField
        SphericallyAberratedBeamTab     matlab.ui.container.Tab
        NAEditField_6Label              matlab.ui.control.Label
        NAEditField_6                   matlab.ui.control.NumericEditField
        amplitudeEditField_2Label       matlab.ui.control.Label
        amplitudeEditField_2            matlab.ui.control.NumericEditField
        DoubleBeamTab                   matlab.ui.container.Tab
        NAEditField_5Label              matlab.ui.control.Label
        NAEditField_5                   matlab.ui.control.NumericEditField
        separationEditFieldLabel        matlab.ui.control.Label
        separationEditField             matlab.ui.control.NumericEditField
        UploadTab                       matlab.ui.container.Tab
        AmplitudeEditFieldLabel         matlab.ui.control.Label
        AmplitudeEditField              matlab.ui.control.EditField
        PhaseEditFieldLabel             matlab.ui.control.Label
        PhaseEditField                  matlab.ui.control.EditField
        wavelengthnmLabel               matlab.ui.control.Label
        wavelengthnmEditField           matlab.ui.control.NumericEditField
        ExcitationDropDownLabel         matlab.ui.control.Label
        ExcitationDropDown              matlab.ui.control.DropDown
    end


    properties (Access = private)
        NA % Description
        aptype % Description
        phasetype % Description
        illPSF_mat % Description
        cy % Description
        cy_pad
        num_par % Description
        illPSF % Description
        PSFSumY % Description
        ml % Description
        os % Description
        length % Description
        sep_double % Description
        options % Description
        saveto % Description
        amptiff % Description
        phasetiff % Description
        amp_in_BFP
        phase_in_BFP
        ref_index = 1.33;
        lambda = 488;
        excitation = 'Linear';
    end


    methods (Access = private)

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            [app.os, app.ml, app.length]=analysisbeam_gui(app.PSFSumY,app.cy,app.num_par);
            if isnan(app.ml)
                app.MainLobeWidthTextArea.Value = 'Error';
                app.OpticalSectioningTextArea.Value = 'Error';
                app.LengthTextArea.Value = 'Error';
                error('Beam too large')
            else
                app.MainLobeWidthTextArea.Value = string(app.ml);
                app.OpticalSectioningTextArea.Value = string(app.os);
                app.LengthTextArea.Value = string(app.length);
            end
        end

        % Value changed function: NAEditField
        function NAEditFieldValueChanged(app, event)
            app.NA = app.NAEditField.Value;
            app.aptype='gaussian';
            app.phasetype=' ';
            
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            close all
            app.MainLobeWidthTextArea.Value = '';
            app.OpticalSectioningTextArea.Value = '';
            app.LengthTextArea.Value = '';
            
            [app.illPSF_mat,illPSF_Ix0,illPSF_EinBFP,app.amp_in_BFP,app.phase_in_BFP,app.cy,app.cy_pad,cz,app.num_par,phys_par,app.illPSF]=simulatebeam_gui(app.aptype,app.phasetype,app.NA,0,app.lambda,app.ref_index,app.excitation,app.options);
            
            [~,app.PSFSumY]=integrate_and_slice_gui(app.illPSF_mat,illPSF_Ix0,illPSF_EinBFP,cz,app.cy,app.cy_pad,app.num_par,phys_par,app.illPSF,app.phase_in_BFP);
        end

        % Value changed function: NAEditField_5
        function NAEditField_5ValueChanged(app, event)
            app.NA = app.NAEditField_5.Value;
            app.aptype='double-gaussian';
            app.phasetype='';
        end

        % Value changed function: separationEditField
        function separationEditFieldValueChanged(app, event)
            app.options = app.separationEditField.Value;
            
        end

        % Value changed function: NAEditField_2
        function NAEditField_2ValueChanged(app, event)
            app.NA = app.NAEditField_2.Value;
            app.aptype='circ';
            app.phasetype='';
        end

        % Value changed function: NAEditField_3
        function NAEditField_3ValueChanged(app, event)
            app.NA = app.NAEditField_3.Value;
            app.aptype='circ';
            app.phasetype='airy';
        end

        % Value changed function: NAEditField_4
        function NAlattice_ValueChanged(app, event)
            app.NA = app.NAEditField_4.Value;
            app.aptype='lattice';
            app.phasetype='';
        end

        % Value changed function: amplitudeEditField
        function amplitudeEditFieldValueChanged(app, event)
            app.options = app.amplitudeEditField.Value;
            
        end

        % Value changed function: epsilonEditField
        function epsilonEditFieldValueChanged(app, event)
            app.options = app.epsilonEditField.Value;
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            amplitude = app.amp_in_BFP(app.cy_pad-app.cy+1:app.cy_pad+app.cy,app.cy_pad-app.cy+1:app.cy_pad+app.cy);
            phase = app.phase_in_BFP(app.cy_pad-app.cy+1:app.cy_pad+app.cy,app.cy_pad-app.cy+1:app.cy_pad+app.cy);
            imwrite(double(amplitude),[app.saveto 'amplitude.tiff'])
            imwrite(double(phase),[app.saveto 'phase.tiff'])
       
        end

        % Value changed function: SaveworkspaceEditField
        function SaveworkspaceEditFieldValueChanged(app, event)
            app.saveto = app.SaveworkspaceEditField.Value;
            
        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
            app.options{1} = imread(app.AmplitudeEditField.Value);
            app.aptype = 'uploadtiff';
            
        end

        % Value changed function: PhaseEditField
        function PhaseEditFieldValueChanged(app, event)
             app.options{2} = imread(app.PhaseEditField.Value);
            
        end

        % Value changed function: NAEditField_6
        function NAEditField_6ValueChanged(app, event)
            app.NA = app.NAEditField_6.Value;
            app.aptype='circ';
            app.phasetype='SA';
            
        end

        % Value changed function: amplitudeEditField_2
        function amplitudeEditField_2ValueChanged(app, event)
            app.options = app.amplitudeEditField_2.Value;
            
        end

        % Value changed function: wavelengthnmEditField
        function wavelengthnmEditFieldValueChanged(app, event)
            app.lambda = app.wavelengthnmEditField.Value;
            
        end

        % Value changed function: refractiveindexEditField
        function refractiveindexEditFieldValueChanged(app, event)
            app.ref_index = app.refractiveindexEditField.Value;
            
        end

        % Callback function
        function ExcitationButtonGroupSelectionChanged(app, event)
            app.excitation = app.ExcitationButtonGroup.SelectedObject;
            
        end

        % Value changed function: ExcitationDropDown
        function ExcitationDropDownValueChanged(app, event)
            app.excitation = app.ExcitationDropDown.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SaveworkspaceEditFieldLabel = uilabel(app.UIFigure);
            app.SaveworkspaceEditFieldLabel.Text = 'Save workspace:';
            app.SaveworkspaceEditField = uieditfield(app.UIFigure, 'text');
            app.SaveworkspaceEditField.ValueChangedFcn = createCallbackFcn(app, @SaveworkspaceEditFieldValueChanged, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.AnalysisPanel = uipanel(app.UIFigure);
            app.AnalysisPanel.Title = 'Analysis';
            app.AnalyzeButton = uibutton(app.AnalysisPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.MainLobeWidthTextAreaLabel = uilabel(app.AnalysisPanel);
            app.MainLobeWidthTextAreaLabel.Text = 'Main Lobe Width';
            app.MainLobeWidthTextArea = uitextarea(app.AnalysisPanel);
            app.MainLobeWidthTextArea.Editable = 'off';
            app.LengthTextAreaLabel = uilabel(app.AnalysisPanel);
            app.LengthTextAreaLabel.Text = 'Length';
            app.LengthTextArea = uitextarea(app.AnalysisPanel);
            app.LengthTextArea.Editable = 'off';
            app.OpticalSectioningTextAreaLabel = uilabel(app.AnalysisPanel);
            app.OpticalSectioningTextAreaLabel.Text = 'Optical Sectioning';
            app.OpticalSectioningTextArea = uitextarea(app.AnalysisPanel);
            app.OpticalSectioningTextArea.Editable = 'off';
            app.refractiveindexEditFieldLabel = uilabel(app.UIFigure);
            app.refractiveindexEditFieldLabel.Text = 'refractive index';
            app.refractiveindexEditField = uieditfield(app.UIFigure, 'numeric');
            app.refractiveindexEditField.ValueChangedFcn = createCallbackFcn(app, @refractiveindexEditFieldValueChanged, true);
            app.refractiveindexEditField.Value = 1.33;
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GaussianBeamTab = uitab(app.TabGroup);
            app.GaussianBeamTab.Title = 'Gaussian Beam';
            app.NAEditFieldLabel = uilabel(app.GaussianBeamTab);
            app.NAEditFieldLabel.Text = 'NA';
            app.NAEditField = uieditfield(app.GaussianBeamTab, 'numeric');
            app.NAEditField.ValueChangedFcn = createCallbackFcn(app, @NAEditFieldValueChanged, true);
            app.FocusedFlatTopBeamTab = uitab(app.TabGroup);
            app.FocusedFlatTopBeamTab.Title = 'Focused Flat Top Beam';
            app.NAEditField_2Label = uilabel(app.FocusedFlatTopBeamTab);
            app.NAEditField_2Label.Text = 'NA';
            app.NAEditField_2 = uieditfield(app.FocusedFlatTopBeamTab, 'numeric');
            app.NAEditField_2.ValueChangedFcn = createCallbackFcn(app, @NAEditField_2ValueChanged, true);
            app.AiryBeamTab = uitab(app.TabGroup);
            app.AiryBeamTab.Title = 'Airy Beam';
            app.NAEditField_3Label = uilabel(app.AiryBeamTab);
            app.NAEditField_3Label.Text = 'NA';
            app.NAEditField_3 = uieditfield(app.AiryBeamTab, 'numeric');
            app.NAEditField_3.ValueChangedFcn = createCallbackFcn(app, @NAEditField_3ValueChanged, true);
            app.amplitudeEditFieldLabel = uilabel(app.AiryBeamTab);
            app.amplitudeEditFieldLabel.Text = 'amplitude';
            app.amplitudeEditField = uieditfield(app.AiryBeamTab, 'numeric');
            app.amplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @amplitudeEditFieldValueChanged, true);
            app.DitheredLatticeTab = uitab(app.TabGroup);
            app.DitheredLatticeTab.Title = 'Dithered Lattice';
            app.NAEditField_4Label = uilabel(app.DitheredLatticeTab);
            app.NAEditField_4Label.Text = 'NA';
            app.NAEditField_4 = uieditfield(app.DitheredLatticeTab, 'numeric');
            app.NAEditField_4.ValueChangedFcn = createCallbackFcn(app, @NAlattice_ValueChanged, true);
            app.epsilonEditFieldLabel = uilabel(app.DitheredLatticeTab);
            app.epsilonEditFieldLabel.Text = 'epsilon';
            app.epsilonEditField = uieditfield(app.DitheredLatticeTab, 'numeric');
            app.epsilonEditField.ValueChangedFcn = createCallbackFcn(app, @epsilonEditFieldValueChanged, true);
            app.SphericallyAberratedBeamTab = uitab(app.TabGroup);
            app.SphericallyAberratedBeamTab.Title = 'Spherically Aberrated Beam';
            app.NAEditField_6Label = uilabel(app.SphericallyAberratedBeamTab);
            app.NAEditField_6Label.Text = 'NA';
            app.NAEditField_6 = uieditfield(app.SphericallyAberratedBeamTab, 'numeric');
            app.NAEditField_6.ValueChangedFcn = createCallbackFcn(app, @NAEditField_6ValueChanged, true);
            app.amplitudeEditField_2Label = uilabel(app.SphericallyAberratedBeamTab);
            app.amplitudeEditField_2Label.Text = 'amplitude';
            app.amplitudeEditField_2 = uieditfield(app.SphericallyAberratedBeamTab, 'numeric');
            app.amplitudeEditField_2.ValueChangedFcn = createCallbackFcn(app, @amplitudeEditField_2ValueChanged, true);
            app.DoubleBeamTab = uitab(app.TabGroup);
            app.DoubleBeamTab.Title = 'Double Beam';
            app.NAEditField_5Label = uilabel(app.DoubleBeamTab);
            app.NAEditField_5Label.Text = 'NA';
            app.NAEditField_5 = uieditfield(app.DoubleBeamTab, 'numeric');
            app.NAEditField_5.ValueChangedFcn = createCallbackFcn(app, @NAEditField_5ValueChanged, true);
            app.separationEditFieldLabel = uilabel(app.DoubleBeamTab);
            app.separationEditFieldLabel.Text = 'separation';
            app.separationEditField = uieditfield(app.DoubleBeamTab, 'numeric');
            app.separationEditField.ValueChangedFcn = createCallbackFcn(app, @separationEditFieldValueChanged, true);
            app.UploadTab = uitab(app.TabGroup);
            app.UploadTab.Title = 'Upload ';
            app.AmplitudeEditFieldLabel = uilabel(app.UploadTab);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.UploadTab, 'text');
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.PhaseEditFieldLabel = uilabel(app.UploadTab);
            app.PhaseEditFieldLabel.Text = 'Phase';
            app.PhaseEditField = uieditfield(app.UploadTab, 'text');
            app.PhaseEditField.ValueChangedFcn = createCallbackFcn(app, @PhaseEditFieldValueChanged, true);
            app.wavelengthnmLabel = uilabel(app.UIFigure);
            app.wavelengthnmLabel.Text = 'wavelength  (nm)';
            app.wavelengthnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.wavelengthnmEditField.ValueChangedFcn = createCallbackFcn(app, @wavelengthnmEditFieldValueChanged, true);
            app.wavelengthnmEditField.Value = 488;
            app.ExcitationDropDownLabel = uilabel(app.UIFigure);
            app.ExcitationDropDownLabel.Text = 'Excitation';
            app.ExcitationDropDown = uidropdown(app.UIFigure);
            app.ExcitationDropDown.Items = {'Linear', 'Two photon'};
            app.ExcitationDropDown.ValueChangedFcn = createCallbackFcn(app, @ExcitationDropDownValueChanged, true);
            app.ExcitationDropDown.Value = 'Linear';
        end
    end

    methods (Access = public)

        % Construct app
        function app = beamsimulator_v2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end